classdef Display_Tool_For_New_Fuzzy_Implication_Model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplayToolUIFigure            matlab.ui.Figure
        ValuesofSlider_2               matlab.ui.control.Slider
        ValuesofLabel                  matlab.ui.control.Label
        ValuesofSlider                 matlab.ui.control.Slider
        ValuesofSliderLabel            matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        ChooseaDisjunctionButtonGroup  matlab.ui.container.ButtonGroup
        LukasiewiczButton_2            matlab.ui.control.RadioButton
        ProbabilisticSumButton         matlab.ui.control.RadioButton
        GdelButton                     matlab.ui.control.RadioButton
        ChooseaNegationButtonGroup_2   matlab.ui.container.ButtonGroup
        YagerButton                    matlab.ui.control.RadioButton
        SuggenoButton_2                matlab.ui.control.RadioButton
        NaturalButton_2                matlab.ui.control.RadioButton
        ChooseaConjunctionButtonGroup  matlab.ui.container.ButtonGroup
        LukasiewiczButton              matlab.ui.control.RadioButton
        AlgebraicProductButton         matlab.ui.control.RadioButton
        MinimumButton                  matlab.ui.control.RadioButton
        STNModelofFuzzyImplicationsLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SelectedNegation    % Handle for the negation function
        SelectedConjunction % Handle for the conjunction function
        SelectedDisjunction % Handle for the disjunction function
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     % Get the screen size
    screenSize = get(0, 'ScreenSize');
    
    % Get the app size (width and height)
    appWidth = app.DisplayToolUIFigure.Position(3);
    appHeight = app.DisplayToolUIFigure.Position(4);
    
    % Calculate the x and y positions to center the app
    xPos = (screenSize(3) - appWidth) / 2;
    yPos = (screenSize(4) - appHeight) / 2;
    
    % Set the UIFigure position
    app.DisplayToolUIFigure.Position(1:2) = [xPos yPos];
    % Initialize default functions (e.g., zero functions or placeholders)
    app.SelectedNegation = @(x) 1-x;     % Default function for negation
    app.SelectedConjunction = @(x,y) min(x,y);  % Default function for conjunction
    app.SelectedDisjunction = @(x,y) max(x,y);  % Default function for disjunction
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             % Define symbolic variables
             syms x y;
        
              % Get the selected functions from the app
              N = app.SelectedNegation;
              T = app.SelectedConjunction;
              S = app.SelectedDisjunction;

              % Construct the final expression directly
              finalExpression = S(T(N(x),N(y)),S(T(N(x),y),T(x,y)));

              % Convert symbolic expression to function handle for plotting
              fHandle = matlabFunction(finalExpression, 'Vars', {x, y});
    
              % Create a mesh grid for plotting
             [X, Y] = meshgrid(linspace(0, 1, 100), linspace(0, 1, 100));
             Z = arrayfun(@(xVal, yVal) fHandle(xVal, yVal), X, Y);
  
             % Plot the result
            surf(app.UIAxes, X, Y, Z); % Use surf for 3D plots; adjust for 2D if needed
            xlim(app.UIAxes, [0, 1]);
             ylim(app.UIAxes, [0, 1]);
             zlim(app.UIAxes, [0, 1]);
        end

        % Selection changed function: ChooseaNegationButtonGroup_2
        function ChooseaNegationButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ChooseaNegationButtonGroup_2.SelectedObject;
            % Perform different actions based on the selected radio button
            switch selectedButton.Text
                case 'Natural'
                    app.SelectedNegation = @(x) 1-x;
                case 'Suggeno'
                    app.SelectedNegation = @(x) (1-x)/(1+app.ValuesofSlider.Value*x);
                case 'Yager'
                    app.SelectedNegation = @(x) (1-x^app.ValuesofSlider_2.Value)^(1/app.ValuesofSlider_2.Value);
            end
        end

        % Selection changed function: ChooseaConjunctionButtonGroup
        function ChooseaConjunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseaConjunctionButtonGroup.SelectedObject;
            % Perform different actions based on the selected radio button
            switch selectedButton.Text
              case 'Minimum'
                    app.SelectedConjunction = @(x,y) min(x,y);
              case 'Algebraic Product'
                    app.SelectedConjunction = @(x,y) x*y;
              case "Lukasiewicz"
                    app.SelectedConjunction = @(x,y) max(x+y-1,0);
            end
        end

        % Selection changed function: ChooseaDisjunctionButtonGroup
        function ChooseaDisjunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseaDisjunctionButtonGroup.SelectedObject;
             % Perform different actions based on the selected radio button
            switch selectedButton.Text
                case 'Gödel'
                    app.SelectedDisjunction = @(x,y) max(x,y);
                case 'Probabilistic Sum'
                    app.SelectedDisjunction = @(x,y) x+y-x*y;
                case "Lukasiewicz"
                    app.SelectedDisjunction = @(x,y) min(x+y,1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DisplayToolUIFigure = uifigure('Visible', 'off');
            app.DisplayToolUIFigure.Name = 'Display Tool';
            app.UIAxes = uiaxes(app.DisplayToolUIFigure);
            title(app.UIAxes, 'Graph of the Custom (S,T,N) - Implication')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.DisplayToolUIFigure);
            app.Image.ImageSource = 'combined_uni.png';
            app.STNModelofFuzzyImplicationsLabel = uilabel(app.DisplayToolUIFigure);
            app.STNModelofFuzzyImplicationsLabel.Text = '(S,T,N) - Model of Fuzzy Implications';
            app.ChooseaConjunctionButtonGroup = uibuttongroup(app.DisplayToolUIFigure);
            app.ChooseaConjunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseaConjunctionButtonGroupSelectionChanged, true);
            app.ChooseaConjunctionButtonGroup.Title = 'Choose a Conjunction';
            app.MinimumButton = uiradiobutton(app.ChooseaConjunctionButtonGroup);
            app.MinimumButton.Text = 'Minimum';
            app.MinimumButton.Value = true;
            app.AlgebraicProductButton = uiradiobutton(app.ChooseaConjunctionButtonGroup);
            app.AlgebraicProductButton.Text = 'Algebraic Product';
            app.LukasiewiczButton = uiradiobutton(app.ChooseaConjunctionButtonGroup);
            app.LukasiewiczButton.Text = 'Lukasiewicz';
            app.ChooseaNegationButtonGroup_2 = uibuttongroup(app.DisplayToolUIFigure);
            app.ChooseaNegationButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ChooseaNegationButtonGroup_2SelectionChanged, true);
            app.ChooseaNegationButtonGroup_2.Title = 'Choose a Negation';
            app.NaturalButton_2 = uiradiobutton(app.ChooseaNegationButtonGroup_2);
            app.NaturalButton_2.Text = 'Natural';
            app.NaturalButton_2.Value = true;
            app.SuggenoButton_2 = uiradiobutton(app.ChooseaNegationButtonGroup_2);
            app.SuggenoButton_2.Text = 'Suggeno';
            app.YagerButton = uiradiobutton(app.ChooseaNegationButtonGroup_2);
            app.YagerButton.Text = 'Yager';
            app.ChooseaDisjunctionButtonGroup = uibuttongroup(app.DisplayToolUIFigure);
            app.ChooseaDisjunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseaDisjunctionButtonGroupSelectionChanged, true);
            app.ChooseaDisjunctionButtonGroup.Title = 'Choose a Disjunction';
            app.GdelButton = uiradiobutton(app.ChooseaDisjunctionButtonGroup);
            app.GdelButton.Text = 'Gödel';
            app.GdelButton.Value = true;
            app.ProbabilisticSumButton = uiradiobutton(app.ChooseaDisjunctionButtonGroup);
            app.ProbabilisticSumButton.Text = 'Probabilistic Sum';
            app.LukasiewiczButton_2 = uiradiobutton(app.ChooseaDisjunctionButtonGroup);
            app.LukasiewiczButton_2.Text = 'Lukasiewicz';
            app.PlotButton = uibutton(app.DisplayToolUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ValuesofSliderLabel = uilabel(app.DisplayToolUIFigure);
            app.ValuesofSliderLabel.Text = 'Values of λ:';
            app.ValuesofSlider = uislider(app.DisplayToolUIFigure);
            app.ValuesofSlider.Limits = [-1 100];
            app.ValuesofSlider.MajorTicks = [-1 100];
            app.ValuesofSlider.MajorTickLabels = {'-1', '∞'};
            app.ValuesofSlider.MinorTicks = [1 3 5 7 9 11 13 15 17 19 21 23 25 27 29 31 33 35 37 39 41 43 45 47 49 51 53 55 57 59 61 63 65 67 69 71 73 75 77 79 81 83 85 87 89 91 93 95 97 100];
            app.ValuesofSlider.Value = -1;
            app.ValuesofLabel = uilabel(app.DisplayToolUIFigure);
            app.ValuesofLabel.Text = 'Values of ω:';
            app.ValuesofSlider_2 = uislider(app.DisplayToolUIFigure);
            app.ValuesofSlider_2.MajorTicks = [0 100];
            app.ValuesofSlider_2.MajorTickLabels = {'0', '∞'};
            app.ValuesofSlider_2.MinorTicks = [-1 1 3 5 7 9 11 13 15 17 19 21 23 25 27 29 31 33 35 37 39 41 43 45 47 49 51 53 55 57 59 61 63 65 67 69 71 73 75 77 79 81 83 85 87 89 91 93 95 97 100];
            % Show the figure after all components are created
            app.DisplayToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Display_Tool_For_New_Fuzzy_Implication_Model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisplayToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplayToolUIFigure)
        end
    end
end